classdef appV2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes3                      matlab.ui.control.UIAxes
        StartButton                  matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
        UITable2                     matlab.ui.control.Table
        ParadaLabel                  matlab.ui.control.Label
        PausaLabel                   matlab.ui.control.Label
        OperandoLabel                matlab.ui.control.Label
        StopButton                   matlab.ui.control.Button
        EspaciooperacionalLabel      matlab.ui.control.Label
        EspacioconfiguracionesLabel  matlab.ui.control.Label
        Button                       matlab.ui.control.Button
        ConectionButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        Property % Description
    end
    
    properties (Access = public)
        %imgcam =  % Description
        Robot;
        PTuerca
        PTornillo
        %Publicadores y suscriptores
        pubjointR1_1;
        pubjointR1_2;
        pubjointR1_3;
        pubjointR1_4;
        pubjointR1_5;
        pubjointR1_6;
        
        pubjointR2_1;
        pubjointR2_2;
        pubjointR2_3;
        pubjointR2_4;
        pubjointR2_5;
        pubjointR2_6;
        
        msgCaderaR1;
        msgHombroR1;
        msgCodoR1;
        msgWristR1;
        msgPinizqR1
        msgPinderR1
        
        msgCaderaR2;
        msgHombroR2;
        msgCodoR2;
        msgWristR2;
        msgPinizqR2
        msgPinderR2
        
        statesR1;
        statesR2;
        
        pos_jointsR1;
        pos_jointsR2;
        
        img;
        Imagen;
        url % Description
    end
    
    methods (Access = public)
        
        function conect(app)
            % conecta matlab con ROS y Gazebo
            % Crea suscriptores y publicadores como propiedades publicas de la app
                 %Inicializaci�n del nodo maestro en ROS
            %rosinit
            
            rosinit('192.168.1.120',11311)
            
            %Robot 1 -> Tornillos
            %Robot 2 -> Tuercas
            
            
            % Se define los publicadores de las juntas para el Robot 1
            app.pubjointR1_1=rospublisher('/robot_1/joint1_position_controller/command','std_msgs/Float64');
            app.pubjointR1_2=rospublisher('/robot_1/joint2_position_controller/command','std_msgs/Float64');
            app.pubjointR1_3=rospublisher('/robot_1/joint3_position_controller/command','std_msgs/Float64');
            app.pubjointR1_4=rospublisher('/robot_1/joint4_position_controller/command','std_msgs/Float64');
            app.pubjointR1_5=rospublisher('/robot_1/joint5_position_controller/command','std_msgs/Float64');
            app.pubjointR1_6=rospublisher('/robot_1/joint6_position_controller/command','std_msgs/Float64');
            
            % Se define los publicadores de las juntas para el Robot 2
            app.pubjointR2_1=rospublisher('/robot_2/joint1_position_controller/command','std_msgs/Float64');
            app.pubjointR2_2=rospublisher('/robot_2/joint2_position_controller/command','std_msgs/Float64');
            app.pubjointR2_3=rospublisher('/robot_2/joint3_position_controller/command','std_msgs/Float64');
            app.pubjointR2_4=rospublisher('/robot_2/joint4_position_controller/command','std_msgs/Float64');
            app.pubjointR2_5=rospublisher('/robot_2/joint5_position_controller/command','std_msgs/Float64');
            app.pubjointR2_6=rospublisher('/robot_2/joint6_position_controller/command','std_msgs/Float64');
            
            %Se definen los handles para los mensajes del Robot 1
            
            app.msgCaderaR1 = rosmessage(app.pubjointR1_1);
            app.msgHombroR1 = rosmessage(app.pubjointR1_2);
            app.msgCodoR1 = rosmessage(app.pubjointR1_3);
            app.msgWristR1 = rosmessage(app.pubjointR1_4);
            app.msgPinizqR1 = rosmessage(app.pubjointR1_5);
            app.msgPinderR1 = rosmessage(app.pubjointR1_6);
            
            %Se definen los handles para los mensajes del Robot 2
            
            app.msgCaderaR2 = rosmessage(app.pubjointR2_1);
            app.msgHombroR2 = rosmessage(app.pubjointR2_2);
            app.msgCodoR2 = rosmessage(app.pubjointR2_3);
            app.msgWristR2 = rosmessage(app.pubjointR2_4);
            app.msgPinizqR2 = rosmessage(app.pubjointR2_5);
            app.msgPinderR2 = rosmessage(app.pubjointR2_6);
            
            %Se define el susbscriptor del Robot 1
            app.statesR1=rossubscriber('/robot_1/joint_states');
            
            %Se define el susbscriptor del Robot 2
            app.statesR2=rossubscriber('/robot_2/joint_states');
            
            %Se guarda el valor de la posici�n de los 2 robots
            app.statesR1.LatestMessage.Position;
            app.statesR2.LatestMessage.Position;
            
            %app.pos_jointsR1=app.statesR1.LatestMessage.Position;
            %app.pos_jointsR2=app.statesR2.LatestMessage.Position;
            
            %Se realiza la conexion a la camara de Gazebo
            app.Imagen = rossubscriber('/camera1/image_raw');
            
            app.UITable.ColumnWidth = {50,50};
            app.UITable2.ColumnWidth = {50,50,50};
            
        end
        
        function actImg(app)  %Actualiza la imagen de la camara de Gazebo
            app.img = app.Imagen.LatestMessage;
            img2 = readImage(app.img);
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
            
            % Display image and stretch to fill axes
            I = imshow(img2, 'Parent', app.UIAxes, ...
                'XData', [1 app.UIAxes.Position(3)], ...
                'YData', [1 app.UIAxes.Position(4)]);
            % Set limits of axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
        end
        
        function ActData(app)
            app.pos_jointsR1=app.statesR1.LatestMessage.Position;
            app.pos_jointsR2=app.statesR2.LatestMessage.Position;
            PosArt = [app.pos_jointsR1, app.pos_jointsR2];
            app.UITable.Data = PosArt;
            PosConf1 = app.Robot.fkine(app.pos_jointsR1(1:4)');
            PosConf2 = app.Robot.fkine(app.pos_jointsR2(1:4)');
            PosConf1(2,4) = PosConf1(2,4)+25;
            PosConf2(2,4) = PosConf2(2,4)-25;
            PosConf = [PosConf1(1:3,4) PosConf2(1:3,4)];
            app.UITable2.Data = PosConf;
        end
        
        function R = CinDir(app)
           
            % Modelo cinematico inverso PhantomX Pincher
            %Definicion de las variables de articulacion
            Lb=13.7;
            L1=10.5;
            L2=10.5;
            L3=11;
            %Construccion del modelo del robot
            L(1) = Link([0 Lb 0 0 0 0], 'modified');
            L(2) = Link([0 0 0 pi/2 0 pi/2], 'modified');
            L(3) = Link([0 0 L1 0 0 0], 'modified');
            L(4) = Link([0 0 L2 0 0 0], 'modified');
            R=SerialLink(L,'name','Phantom Pincher');
            R.tool=[[0 1 0 0]' [0 0 1 0]' [1 0 0 0]' [L3 0 0 1]'];
        end
        
        function [qsolR1,qsolR2]=trajectory(app, p_objetivo1, p_objetivo2,p_ensamble1)
   
            n=8;
            
            qsolR1=zeros(n,6);
            qsolR2=zeros(n,6);
            
            p_iniRo1=[0 -11.7 23.418 0];
            p_roscado1=[0 -19.7 23.418 0];
            p_iniRo2=[0 20.9 24.6 pi/2];
            p_roscado2=[0 20.9 24.6 pi/2];  
            
            
            q_home1=[0 0 0 0 0 0];
            q_home2=[0 0 0 0 0 0];
            [~,q_iniRo1]=phantomInversa(app,[0 -11.7 23.418 0],0);
            [~,q_iniRo2]=phantomInversa(app,[0 20.9 24.6 pi/2],0);
            [~,q_objetivo1]=phantomInversa(app,p_objetivo1,1);
            [~,q_objetivo2]=phantomInversa(app,p_objetivo2,1);
            [~,q_ensamble1]=phantomInversa(app,p_ensamble1,0);
            
            traR1_1=jtraj(q_home1,q_objetivo1,n);
            traR2_1=jtraj(q_home2,q_objetivo2,n);
            
            qsolR1(1:n,:)=traR1_1;
            qsolR2(1:n,:)=traR2_1;
            
            %Agarra el objeto
            qsolR1(n+1,:)=[qsolR1(n,1:4) 1 1];
            qsolR2(n+1,:)=[qsolR2(n,1:4) 1 1];
            
            %Va a la posición de inicio de roscado
            traR1_2=jtraj([q_objetivo1(1:4) 1 1],q_iniRo1,n);
            traR2_2=jtraj([q_objetivo2(1:4) 1 1],q_iniRo2,n);
            
            qsolR1=[qsolR1;traR1_2;];
            qsolR2=[qsolR2;traR2_2;];
            
            %Hace el roscado
            traR1_3=trayectoriaP(app,p_roscado1,p_iniRo1,n);
            traR2_3=trayectoriaP(app,p_roscado2,p_iniRo2,n);
            
            for i=1:n
                [~,qr1_3]=phantomInversa(app,traR1_3(:,i),0);
                qsolR1(2*n+1+i,:)=qr1_3;
            
                [~,qr2_3]=phantomInversa(app,traR2_3(:,i),0);
                qsolR2(2*n+1+i,:)=qr2_3;
            end
            
            %Uno de los robots suelta las pinzas -> R1
            qsolR1(3*n+2,:)=[qsolR1(3*n+1,1:4) 0 0];
            qsolR2(3*n+2,:)=[qsolR2(3*n+1,1:4) 1 1];
            
            %Se devuelve a la posicion de inicio de roscado
            
            traR1_4=trayectoriaP(app,p_iniRo1,p_roscado1,n);
            traR2_4=trayectoriaP(app,p_iniRo2,p_roscado2,n);
            
            for i=1:n
                [~,qr1_4]=phantomInversa(app,traR1_4(:,i),1);
                qsolR1(3*n+2+i,:)=qr1_4;
            
                [~,qr2_4]=phantomInversa(app,traR2_4(:,i),0);
                qsolR2(3*n+2+i,:)=qr2_4;
            end
            
            %Se uno de los robots va a HOME, el otro va a la estación de ensamble
            
            traR1_5=jtraj([q_iniRo1(1:4) 0 0],q_home1,n);
            traR2_5=jtraj([q_iniRo2(1:4) 1 1],q_ensamble1,n);
            
            qsolR1=[qsolR1;traR1_5;];
            qsolR2=[qsolR2;traR2_5;];
            
            %Suelta el objeto
            
            qsolR1(5*n+3,:)=[qsolR1(5*n+2,1:4) 0 0];
            qsolR2(5*n+3,:)=[qsolR2(5*n+2,1:4) 0 0];
            
            %Se duelve a HOME
            traR1_6=jtraj(q_home1,q_home1,n);
            traR2_6=jtraj([q_ensamble1(1:4) 0 0],q_home2,n);
            
            qsolR1=[qsolR1;traR1_6;];
            qsolR2=[qsolR2;traR2_6;];
        end
        
        function move(app, qsolR1,qsolR2)
            
            n=8;
            
            for i=1:length(qsolR1)
                
                app.msgCaderaR1.Data=qsolR1(i,1);
                app.msgHombroR1.Data=qsolR1(i,2);
                app.msgCodoR1.Data=qsolR1(i,3);
                app.msgWristR1.Data=qsolR1(i,4);
                app.msgPinizqR1.Data=qsolR1(i,5);
                app.msgPinderR1.Data=qsolR1(i,6);
                
                app.msgCaderaR2.Data=qsolR2(i,1);
                app.msgHombroR2.Data=qsolR2(i,2);
                app.msgCodoR2.Data=qsolR2(i,3);
                app.msgWristR2.Data=qsolR2(i,4);
                app.msgPinizqR2.Data=qsolR2(i,5);
                app.msgPinderR2.Data=qsolR2(i,6);
                
                send(app.pubjointR1_1,app.msgCaderaR1)
                send(app.pubjointR1_2,app.msgHombroR1)
                send(app.pubjointR1_3,app.msgCodoR1)
                send(app.pubjointR1_4,app.msgWristR1)
                send(app.pubjointR1_5,app.msgPinizqR1)
                send(app.pubjointR1_6,app.msgPinderR1)
                
                send(app.pubjointR2_1,app.msgCaderaR2)
                send(app.pubjointR2_2,app.msgHombroR2)
                send(app.pubjointR2_3,app.msgCodoR2)
                send(app.pubjointR2_4,app.msgWristR2)
                send(app.pubjointR2_5,app.msgPinizqR2)
                send(app.pubjointR2_6,app.msgPinderR2)
                
                j=1;
                k=1;
                er=0.2;
                while j || k
                    app.pos_jointsR1=app.statesR1.LatestMessage.Position;
                    app.pos_jointsR2=app.statesR2.LatestMessage.Position;
                    
                    sol1 = fix(100*qsolR1(i,:));
                    res1 = fix(100*app.pos_jointsR1');
                    sol2 = fix(100*qsolR2(i,:));
                    res2 = fix(100*app.pos_jointsR2');
                    
                    if(res1(3)>0)
                       res1(3)=100;
                    end
                    if(res1(4)>0)
                       res1(4)=100;
                    end
                    if(res2(3)>0)
                        res2(3)=100;
                    end
                    if(res2(4)>0)
                        res2(4)=100;
                    end
               
                   %Comparación 
                    if(i==n || i==n+1 || i==2*n+1 || i==3*n+2 || i==4*n+3 || i==3*n+2+1 ||i==4*n+2)
               
                    if(sol1(1)==res1(1) && sol1(2)==res1(5) && sol1(3)==res1(2) && sol1(4)==res1(6) && sol1(5)==res1(3) && sol1(6)==res1(4))
                        j=0;
                    end
                    if(sol2(1)==res2(1) && sol2(2)==res2(5) && sol2(3)==res2(2) && sol2(4)==res2(6) && sol2(5)==res2(3) && sol2(6)==res2(4))
                        k=0;
                    end
                    else
                        compar(1,1)=(sol1(1)>=res1(1)+res1(1)*er || sol1(1)>=res1(1)-res1(1)*er);
                        compar(1,2)=(sol1(2)>=res1(5)+res1(5)*er || sol1(2)>=res1(5)-res1(5)*er);
                        compar(1,3)=(sol1(3)>=res1(2)+res1(2)*er || sol1(3)>=res1(2)-res1(2)*er);
                        compar(1,4)=(sol1(4)>=res1(6)+res1(6)*er || sol1(4)>=res1(6)-res1(6)*er);
                        compar(1,5)=(sol1(5)>=res1(3)+res1(3)*er || sol1(5)>=res1(3)-res1(3)*er);
                        compar(1,6)=(sol1(6)>=res1(4)+res1(4)*er || sol1(6)>=res1(4)-res1(4)*er);
                       
                        compar(2,1)=(sol2(1)>=res2(1)+res2(1)*er || sol2(1)>=res2(1)-res2(1)*er);
                        compar(2,2)=(sol2(2)>=res2(5)+res2(5)*er || sol2(2)>=res2(5)-res2(5)*er);
                        compar(2,3)=(sol2(3)>=res2(2)+res2(2)*er || sol2(3)>=res2(2)-res2(2)*er);
                        compar(2,4)=(sol2(4)>=res2(6)+res2(6)*er || sol2(4)>=res2(6)-res2(6)*er);
                        compar(2,5)=(sol2(5)>=res2(3)+res2(3)*er || sol2(5)>=res2(3)-res2(3)*er);
                        compar(2,6)=(sol2(6)>=res2(4)+res2(4)*er || sol2(6)>=res2(4)-res2(4)*er);
               
                        if(compar(1,:))
                            j=0;
                        end
                        if(compar(2,:))
                            k=0;
                        end
           
                    end
                    actImg(app);
                    ActData(app);
                    GetImage(app);
                    pause(0.2);
                end
            end
            
            %rosshutdown
            
        end
        
        function [q_re1,q_re2] = phantomInversa(app,pos,abierto)
            %La entrada a la función es un vector con la posición espacial del efector
            %final y la orientación del efector
            Xt=pos(1);
            Yt=pos(2);
            Zt=pos(3);
            alpha=pos(4);
            
            Lb=13.7;
            L1=10.5;
            L2=10.5;
            L3=11;
            
            %Transformación al punto de la muñeca
            T_tool=[rotx(alpha) abs([Xt Yt Zt])';[0 0 0 1]];
            
            T_w=T_tool*transl(0,-L3,0);
            
            Xw=T_w(1,4); Yw=T_w(2,4); Zw=T_w(3,4);
            
            %Calculos geométricos
            q1 = atan2(Yt,Xt);
            r = (Yw^2 + Xw^2)^0.5;
            c3 = (((Zw-Lb)^2 + r^2) - (L1^2 + L2^2))/(2*L1*L2);
            s3 = (1 - (c3)^2)^0.5;
            q3a = atan2(s3, c3);
            q3b = atan2(-s3, c3);
            
            q2a = atan2 (Zw - Lb, r )+ atan2 (-L2*s3 , L1 + L2*c3);
            q2b = atan2 (Zw - Lb , r )- atan2 (-L2*s3 , L1 + L2*c3);
            
            q4a= alpha - (q2a+q3a);
            q4b= alpha - (q2b+q3b);
            
            %Ajuste por offset en ROS
            q2a=q2a-pi/2;
            q2b=q2b-pi/2;
            
            if(abierto==1)
                q_re1=[q1 q2a q3a q4a 0 0];
                q_re2=[q1 q2b q3b q4b 0 0];
            else
                q_re1=[q1 q2a q3a q4a 1 1];
                q_re2=[q1 q2b q3b q4b 1 1];
            end
        end

        
        function GetImage(app)
            %Conexión cámara
            
            app.url = 'http://192.168.1.107:8080/shot.jpg';

            piezas  = imread(app.url);
            
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
            
            % Display image and stretch to fill axes
            I = imshow(piezas, 'Parent', app.UIAxes3);
            % Set limits of axes
            app.UIAxes3.XLim = [0 I.XData(2)];
            app.UIAxes3.YLim = [0 I.YData(2)];
            
        end
        
        function [xyTuercas, xyTornillos] = Vision(app)
            % Limpiado y transformación a imagen binaria
            
            app.url = 'http://192.168.1.107:8080/shot.jpg';
            piezas2 = imread(app.url);
            
            %Escala de grises
            %rgb = imread('prueba2.jpeg');
            Ibw = rgb2gray(piezas2); 
            % Ajuste contraste
            Ibw =imadjust(Ibw,[0.3 0.8],[]);
            %imshow(Ibw) 
            %Contornos
            neighborhood = ones(9);
            If = stdfilt(Ibw, neighborhood);
            %imshow(If,[])
            %Filtro de contornos
            Imask = If>10;
            %Llenado de contornos
            Imask = imfill(Imask,'holes');
            %imshow(Imask)
            %Filtro de mediana
            Ic = medfilt2(Imask,[30 30]);
            %imshow(Ic)
            %Identificación y etiquetado de regiones
            [L, Ne]=bwlabel(Ic);
            %Propiedades de interés
            props=regionprops(L,'Centroid','Circularity','Orientation','BoundingBox');
            %Visualización
            %rgbLabel=label2rgb(L,'spring','c','shuffle');
            %figure
            %imshow(rgbLabel)
            %Clasificación
            circul=vertcat(props.Circularity);
            piezas=circul>0.9;
            %Centroides
            centr=vertcat(props.Centroid);
            xyTuercas=centr(piezas==1,:);
            xyTornillos=centr(piezas==0,:);
            %Visualización 
                       
            app.UIAxes2.XAxis.TickLabels = {};
            app.UIAxes2.YAxis.TickLabels = {};
            hold(app.UIAxes2,"on")
            % Display image and stretch to fill axes
            I = imshow(piezas2, 'Parent', app.UIAxes2);
            % Set limits of axes
            
            nt=1; no=1;
            for i=1:length(piezas)
                if (piezas(i)==1)
                    text(app.UIAxes2,xyTuercas(nt,1),xyTuercas(nt,2),'Tuerca','FontSize',20,'Color','r');
                    nt=nt+1;
                else
                    text(app.UIAxes2,xyTornillos(no,1),xyTornillos(no,2),'Tornillo','FontSize',20,'Color','b');
                    no=no+1;
                end
            end
            for n=1:Ne
                rectangle(app.UIAxes2,'Position',props(n).BoundingBox,"Edgecolor",[0 1 1],"LineWidth",5);
            end
            hold(app.UIAxes2,"off")
            
            imgsize=size(Ic) %alto y ancho
            xyTornillos=xyTornillos.*[8/imgsize(2) 12/imgsize(1)]
            xyTuercas=xyTuercas.*[8/imgsize(2) 12/imgsize(1)]
            
            app.UIAxes2.XLim = [0 I.XData(2)];
            app.UIAxes2.YLim = [0 I.YData(2)];

        end
        
        function ActPh(app, img)
            
            app.UIAxes3.XAxis.TickLabels = {};
            app.UIAxes3.YAxis.TickLabels = {};
            
            % Display image and stretch to fill axes
            I = imshow(img, 'Parent', app.UIAxes3, ...
                'XData', [1 app.UIAxes3.Position(3)], ...
                'YData', [1 app.UIAxes3.Position(4)]);
            % Set limits of axes
            app.UIAxes3.XLim = [0 I.XData(2)];
            app.UIAxes3.YLim = [0 I.YData(2)];
            
        end
        
        function [arreglo_pos] = trayectoriaP(app, P_objetivo,P_actual,n)
            %P_objetivo es un vector de 4 posiciones [X Y Z alpha]'
            
            %pos_actual es la matriz de transformación homogenea resultado del fkine de
            %los valores articulares del robot.
            
            
            T_objetivo=[rotx(P_objetivo(4)) [P_objetivo(1) P_objetivo(2) P_objetivo(3)]';[0 0 0 1]];
            T_actual=[rotx(P_actual(4)) [P_actual(1) P_actual(2) P_actual(3)]';[0 0 0 1]];
            
            Trayec=ctraj(T_actual,T_objetivo,n);
            arreglo_pos=zeros(4,n);
            
            for i=1:n
                arreglo_pos(1:3,i)=Trayec(1:3,4,i);
                arreglo_pos(4,i)=acos(Trayec(3,3,i));
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.Robot = CinDir(app);
            app.ParadaLabel.Visible = 0;
            app.PausaLabel.Visible = 0;
            actImg(app);
            ActData(app);
            %p_objetivo1=[13 15 4.5 -pi/2; 10 12 4.5 -pi/2];
            %p_objetivo2=[13 -15 4.5 -pi/2; 10 -12 4.5 -pi/2];
            p_ensamble1=[-15 0 10 0];
            p_ensamble2=[-12 0 10 0];
            p_ensamble3=[-20 0 10 0];
            
            [xyTuercas, xyTornillos] = Vision(app);
            
            p_objetivo1=zeros(size(xyTuercas,1),4);
            p_objetivo2=zeros(size(xyTuercas,1),4);
            
            for i=1:size(xyTornillos,1)
                p_objetivo1(i,:)=[rotz(-pi/2) [8 -6 0]';[0 0 0 1]]*[xyTornillos(i,:)';4.5;1];
                p_objetivo1(i,4)=-pi/2;
            end
            
            for i=1:size(xyTuercas,1)
                p_objetivo2(i,:)=[rotz(-pi/2) [8 -6 0]';[0 0 0 1]]*[xyTuercas(i,:)';4.5;1];
                p_objetivo2(i,4)=-pi/2;
            end
            
            [p1size,~]=size(p_objetivo1);
            [p2size,~]=size(p_objetivo2);
            
            [q1,q2] = trajectory(app,p_objetivo1(1,:),p_objetivo2(1,:),p_ensamble1);
             move(app,q1,q2);
                
            if((p1size==2)&&(p2size==2) || (p1size==3)&&(p2size==3))
                [q1,q2] = trajectory(app,p_objetivo1(2,:),p_objetivo2(2,:),p_ensamble2);
                 move(app,q1,q2);
            end
            
            if((p1size==3)&&(p2size==3))
                [q1,q2] = trajectory(app,p_objetivo1(3,:),p_objetivo2(3,:),p_ensamble3);
                 move(app,q1,q2);
            end
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.ParadaLabel.Visible = 1;
            app.PausaLabel.Visible = 1;
            rosshutdown;
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %Img = GetImage(app);
            [app.PTuerca, app.PTornillo] = Vision(app);
            %ActPh(app, photo);
            %move(app, app.PTuerca, app.PTornillo)
        end

        % Button pushed function: ConectionButton
        function ConectionButtonPushed(app, event)
            conect(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, ' ')
            xlabel(app.UIAxes, ' ')
            ylabel(app.UIAxes, ' ')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, {''; ''})
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, ' ')
            xlabel(app.UIAxes3, ' ')
            ylabel(app.UIAxes3, ' ')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = {'Start'; ''};
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'R1'; 'R2'};
            app.UITable.RowName = {'q1'; 'q2'; 'q3'; 'q4'; 'gripper '};
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'R1'; 'R2'};
            app.UITable2.RowName = {'X'; 'Y'; 'Z'; 'R'; 'P'; 'Y'};
            app.ParadaLabel = uilabel(app.UIFigure);
            app.ParadaLabel.Text = {'Parada'; ''};
            app.PausaLabel = uilabel(app.UIFigure);
            app.PausaLabel.Text = {'Pausa'; ''};
            app.OperandoLabel = uilabel(app.UIFigure);
            app.OperandoLabel.Text = {'Operando'; ''};
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = {'Stop'; ''};
            app.EspaciooperacionalLabel = uilabel(app.UIFigure);
            app.EspaciooperacionalLabel.Text = {'Espacio '; 'operacional'};
            app.EspacioconfiguracionesLabel = uilabel(app.UIFigure);
            app.EspacioconfiguracionesLabel.Text = {'Espacio '; 'configuraciones'};
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'CAM.jpeg';
            app.Button.Text = '';
            app.ConectionButton = uibutton(app.UIFigure, 'push');
            app.ConectionButton.ButtonPushedFcn = createCallbackFcn(app, @ConectionButtonPushed, true);
            app.ConectionButton.Text = 'Conection';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appV2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end